classdef design_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AlgorithmDropDownLabel       matlab.ui.control.Label
        AlgorithmDropDown            matlab.ui.control.DropDown
        GraphClusteringToolkitLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        UploadDataButton             matlab.ui.control.Button
        ClusterButton                matlab.ui.control.Button
        ClustersSliderLabel          matlab.ui.control.Label
        ClustersSlider               matlab.ui.control.Slider
        DataSetDropDownLabel         matlab.ui.control.Label
        DataSetDropDown              matlab.ui.control.DropDown
        UserDataLabel                matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        CalculatingLabel             matlab.ui.control.Label
    end


    properties (Access = private)
        UsedDataSet = struct('data',[],'labels',[],'name','');
    end

    methods (Access = private)
    
        function [data,labels] = parse_dataset(app,filename)
            [~,file,ext] = fileparts(filename);
            app.UserDataLabel.Text = [file ext];
            [data,labels] = import_graph_by_edges(filename);
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
        end

        % Button pushed function: ClusterButton
        function ClusterButtonPushed(app, event)
            cla(app.UIAxes);
            drawnow;
            paired = [0.6510    0.8078    0.8902;
                      0.1216    0.4706    0.7059;
                      0.6980    0.8745    0.5412;
                      0.2000    0.6275    0.1725;
                      0.9843    0.6039    0.6000;
                      0.8902    0.1020    0.1098;
                      0.9922    0.7490    0.4353
                      1.0000    0.4980         0;
                      0.7922    0.6980    0.8392;
                      0.4157    0.2392    0.6039];
            colormap(app.UIAxes,paired);
            app.CalculatingLabel.Text = 'Calculating...';
            graph = app.UsedDataSet.data;
            labels = app.UsedDataSet.labels; 
            k = app.ClustersSlider.Value;
            algo = app.AlgorithmDropDown.Value;
            switch algo
                case 'Spectral Clustering'
                    deg = sum(graph);
                    lap = diag(1./deg)*graph;
                    [U,~] = eigs(lap,k);
                    L_hat = kmeans(U,k);
                    L_hat = get_true_emb(L_hat,labels);
                    ccr = sum(L_hat == labels')*100/numel(labels);
                    nmi = get_nmi(L_hat,labels);
                case 'Vec-NBT'
                    [U,L_hat,ccr,nmi] = node_embed_file(graph,labels,k,1,30);
               case 'RSP'
                    [U,L_hat,ccr,nmi] = CRSP({graph},{1./graph},labels,size(graph,1),k,1,.02);
                otherwise
                    error('Must choose one of the options given; this is literally impossible');
            end
            app.CalculatingLabel.Text = '';
            tsne(U,L_hat,2,app.UIAxes);
            app.UITable.Data = {[num2str(ccr) '%'], num2str(nmi)};
        end

        % Value changed function: ClustersSlider
        function ClustersSliderValueChanged(app, event)
            app.ClustersSlider.Value = round(app.ClustersSlider.Value);
        end

        % Value changed function: DataSetDropDown
        function DataSetDropDownValueChanged(app, event)
            value = app.DataSetDropDown.Value;
            if strcmp(value,'User Defined')
                app.UploadDataButton.Enable = 'on';
            else
                app.UploadDataButton.Enable = 'off';
                if strcmp(value,'SBM') && ~strcmp(app.UsedDataSet.name,'sbm')
                    [data,labels] = parse_dataset(app,'examples/sbm.txt');
                    app.UsedDataSet.name = 'sbm';
                elseif strcmp(value,'3Sources') && ~strcmp(app.UsedDataSet.name,'3sources')
                    [data,labels] = parse_dataset(app,'examples/3sources.txt');
                    app.UsedDataSet.name = '3sources';
                elseif strcmp(value,'UK Politics') && ~strcmp(app.UsedDataSet.name,'poluk')
                    [data,labels] = parse_dataset(app,'examples/poluk1.txt');
                    app.UsedDataSet.name = 'poluk';
                end
                app.UsedDataSet.data = data;
                app.UsedDataSet.labels = labels;
                app.ClustersSlider.Value = max(labels);
            end
        end

        % Button pushed function: UploadDataButton
        function UploadDataButtonPushed(app, event)
            [file,path] = uigetfile('examples/*.*');
            if file == 0
                file = '';
            end
            if strcmp(app.UserDataLabel.Text, file)
            else
                [data,labels] = parse_dataset(app,fullfile(path,file));
                app.UsedDataSet.name = 'userdef';
                app.UsedDataSet.data = data;
                app.UsedDataSet.labels = labels;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.AlgorithmDropDownLabel = uilabel(app.UIFigure);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.UIFigure);
            app.AlgorithmDropDown.Items = {'Spectral Clustering', 'Vec-NBT', 'RSP'};
            app.AlgorithmDropDown.Value = 'Spectral Clustering';
            app.GraphClusteringToolkitLabel = uilabel(app.UIFigure);
            app.GraphClusteringToolkitLabel.Text = 'Graph Clustering Toolkit';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Visualized Clusters')
            app.UploadDataButton = uibutton(app.UIFigure, 'push');
            app.UploadDataButton.ButtonPushedFcn = createCallbackFcn(app, @UploadDataButtonPushed, true);
            app.UploadDataButton.Enable = 'off';
            app.UploadDataButton.Text = 'Upload Data';
            app.ClusterButton = uibutton(app.UIFigure, 'push');
            app.ClusterButton.ButtonPushedFcn = createCallbackFcn(app, @ClusterButtonPushed, true);
            app.ClusterButton.Text = 'Cluster';
            app.ClustersSliderLabel = uilabel(app.UIFigure);
            app.ClustersSliderLabel.Text = 'Clusters';
            app.ClustersSlider = uislider(app.UIFigure);
            app.ClustersSlider.Limits = [2 6];
            app.ClustersSlider.MajorTicks = [2 3 4 5 6];
            app.ClustersSlider.ValueChangedFcn = createCallbackFcn(app, @ClustersSliderValueChanged, true);
            app.ClustersSlider.MinorTicks = [];
            app.ClustersSlider.Value = 2;
            app.DataSetDropDownLabel = uilabel(app.UIFigure);
            app.DataSetDropDownLabel.Text = 'Data Set';
            app.DataSetDropDown = uidropdown(app.UIFigure);
            app.DataSetDropDown.Items = {'', 'SBM', '3Sources', 'UK Politics', 'User Defined'};
            app.DataSetDropDown.ValueChangedFcn = createCallbackFcn(app, @DataSetDropDownValueChanged, true);
            app.DataSetDropDown.Value = '';
            app.UserDataLabel = uilabel(app.UIFigure);
            app.UserDataLabel.Text = '';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'CCR'; 'NMI'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.CalculatingLabel = uilabel(app.UIFigure);
            app.CalculatingLabel.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = design_gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end